classdef test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Bild             matlab.ui.control.UIAxes
        fHzKnobLabel     matlab.ui.control.Label
        FrequenzKnopf    matlab.ui.control.Knob
        Titel            matlab.ui.control.Label
        AVKnobLabel      matlab.ui.control.Label
        AmplitudenKnopf  matlab.ui.control.Knob
    end

    
    properties (Access = private)
        
        % Varialben Deklaration
        %----------------------
        T=0:0.01:5;     % Zeitvektor
        Amp=1;          % Amplitude (Startwert) [V]
        freq=1;         % Frequenz (Startwert)  [Hz]
        % Anmerkung:
        % Wenn man den Variablen keinen Wert zuweist ist die Dimension
        % unbestimmt. Vielleicht funktioniert auch Amp=zeros(1,1), 
        % wenn man keine Anfangswerte zuweisen möchte.
        
    end
    
    methods (Access = private)
        % Funktion um 
        function [] = plot_func(app)
        % Anmerkung:
        % Nach jeder Parameteränderung (hier freq und Amp) muss die
        % Berechnung neu ausgeführt werden. Diese function wird von
        % 
           y= app.Amp*sin(2*pi*app.freq*app.T); 
           plot(app.Bild,app.T,y)  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Anfangswerte(app)
        % Diese function wird direkt nach starten der App aufgerufen
        % und setzt somit definierte Anfangswerte.
            plot_func(app) 
        end

        % Value changing function: FrequenzKnopf
        function FrequenzKnopfValueChanging(app, event)
        % Liest die Frequenzwerte ein
        %----------------------------
            app.freq = event.Value;
            plot_func(app)
        end

        % Value changing function: AmplitudenKnopf
        function AmplitudenKnopfValueChanging(app, event)
        % Liest die Amplitudenwerte ein
        %------------------------------   
            app.Amp= event.Value;
            plot_func(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Bild = uiaxes(app.UIFigure);
            title(app.Bild, 'Sinus')
            xlabel(app.Bild, 't [s]')
            ylabel(app.Bild, 'A [V]')
            app.Bild.XLim = [0 5];
            app.Bild.YLim = [-10 10];
            app.fHzKnobLabel = uilabel(app.UIFigure);
            app.fHzKnobLabel.Text = 'f [Hz]';
            app.FrequenzKnopf = uiknob(app.UIFigure, 'continuous');
            app.FrequenzKnopf.Limits = [1 3];
            app.FrequenzKnopf.MajorTicks = [1 1.5 2 2.5 3];
            app.FrequenzKnopf.ValueChangingFcn = createCallbackFcn(app, @FrequenzKnopfValueChanging, true);
            app.FrequenzKnopf.Value = 1;
            app.Titel = uilabel(app.UIFigure);
            app.Titel.Text = 'Beispiel 1';
            app.AVKnobLabel = uilabel(app.UIFigure);
            app.AVKnobLabel.Text = 'A [V]';
            app.AmplitudenKnopf = uiknob(app.UIFigure, 'continuous');
            app.AmplitudenKnopf.Limits = [0 10];
            app.AmplitudenKnopf.ValueChangingFcn = createCallbackFcn(app, @AmplitudenKnopfValueChanging, true);
            app.AmplitudenKnopf.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Anfangswerte)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end